classdef imgediter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        outputImageLabel_2    matlab.ui.control.Label
        outputImage_2         matlab.ui.control.Image
        outputImageLabel      matlab.ui.control.Label
        outputImage           matlab.ui.control.Image
        gammaLabel            matlab.ui.control.Label
        cLabel                matlab.ui.control.Label
        hasilLabel            matlab.ui.control.Label
        soalMenu              matlab.ui.container.ButtonGroup
        soal4Button           matlab.ui.control.RadioButton
        soal3Button           matlab.ui.control.RadioButton
        soal2Button           matlab.ui.control.RadioButton
        soal1Button           matlab.ui.control.RadioButton
        subSoal2Menu          matlab.ui.container.ButtonGroup
        soal2eButton          matlab.ui.control.RadioButton
        soal2dButton          matlab.ui.control.RadioButton
        soal2cButton          matlab.ui.control.RadioButton
        soal2bButton          matlab.ui.control.RadioButton
        soal2aButton          matlab.ui.control.RadioButton
        gammaEditField        matlab.ui.control.NumericEditField
        cEditField            matlab.ui.control.NumericEditField
        aEditField            matlab.ui.control.NumericEditField
        aLabel                matlab.ui.control.Label
        bEditField            matlab.ui.control.NumericEditField
        bEditFieldLabel       matlab.ui.control.Label
        inputImageLabel       matlab.ui.control.Label
        appTitle              matlab.ui.control.Label
        inputImage            matlab.ui.control.Image
        prosesButton          matlab.ui.control.Button
        browseButton          matlab.ui.control.Button
        inputimgfield         matlab.ui.control.EditField
        outputHist_2Gabungan  matlab.ui.control.UIAxes
        outputHistGabungan    matlab.ui.control.UIAxes
        inputHistGabungan     matlab.ui.control.UIAxes
        outputHist_2B         matlab.ui.control.UIAxes
        outputHist_2G         matlab.ui.control.UIAxes
        outputHist_2R         matlab.ui.control.UIAxes
        outputHistB           matlab.ui.control.UIAxes
        outputHistG           matlab.ui.control.UIAxes
        outputHistR           matlab.ui.control.UIAxes
        inputHistB            matlab.ui.control.UIAxes
        inputHistG            matlab.ui.control.UIAxes
        inputHistR            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        inputPath % Description
        inputImageData 
        refImg
        exte
    end
    
    methods (Access = public)
        
                       % TAMBAHKAN METHOD BARU INI DI DALAM CLASSDEF ANDA
            function plotFourHistograms(app, imageData, axesPrefix)
                % Fungsi ini akan memplot histogram ke 4 axes yang relevan
                % (R, G, B, dan Gabungan) berdasarkan prefix nama.
                % Contoh: axesPrefix = 'inputHist' atau 'outputHist'
            
                % Nama-nama axes target
                axesR = app.([axesPrefix 'R']);
                axesG = app.([axesPrefix 'G']);
                axesB = app.([axesPrefix 'B']);
                axesCombined = app.([axesPrefix 'Gabungan']); % Asumsi nama axes ke-4
            
                % Bersihkan semua axes terlebih dahulu
                cla(axesR, 'reset'); cla(axesG, 'reset'); 
                cla(axesB, 'reset'); cla(axesCombined, 'reset');
            
                if isempty(imageData)
                    return;
                end
            
                isColor = size(imageData, 3) == 3;
            
                if isColor
                    % --- UNTUK GAMBAR BERWARNA ---
                    R = imageData(:,:,1); G = imageData(:,:,2); B = imageData(:,:,3);
                    
                    % Plot Histogram Merah
                    countsR = custom_image_histogram(R);
                    bar(axesR, 0:255, countsR, 'FaceColor', 'r', 'EdgeColor', 'none');
                    title(axesR, 'Histogram Red'); axesR.Visible = 'on';
            
                    % Plot Histogram Hijau
                    countsG = custom_image_histogram(G);
                    bar(axesG, 0:255, countsG, 'FaceColor', 'g', 'EdgeColor', 'none');
                    title(axesG, 'Histogram Green'); axesG.Visible = 'on';
            
                    % Plot Histogram Biru
                    countsB = custom_image_histogram(B);
                    bar(axesB, 0:255, countsB, 'FaceColor', 'b', 'EdgeColor', 'none');
                    title(axesB, 'Histogram Blue'); axesB.Visible = 'on';
            
                    % Plot Histogram Gabungan (Overlay)
                    hold(axesCombined, 'on');
                    bar(axesCombined, 0:255, countsR, 'FaceColor', 'r', 'EdgeColor', 'none', 'FaceAlpha', 0.6);
                    bar(axesCombined, 0:255, countsG, 'FaceColor', 'g', 'EdgeColor', 'none', 'FaceAlpha', 0.6);
                    bar(axesCombined, 0:255, countsB, 'FaceColor', 'b', 'EdgeColor', 'none', 'FaceAlpha', 0.6);
                    hold(axesCombined, 'off');
                    legend(axesCombined, 'R', 'G', 'B');
                    title(axesCombined, 'Histogram Gabungan'); axesCombined.Visible = 'on';
                    
                else % --- UNTUK GAMBAR GRAYSCALE ---
                    counts = custom_image_histogram(imageData);
            
                    % Tampilkan di axes gabungan, sembunyikan yang lain
                    bar(axesCombined, 0:255, counts, 'FaceColor', 'k', 'EdgeColor', 'none');
                    title(axesCombined, 'Histogram Grayscale');
                    axesCombined.Visible = 'on';
                    
                    axesR.Visible = 'off';
                    axesG.Visible = 'off';
                    axesB.Visible = 'off';
                end
            end


         function plotCustomHistogram(app, targetAxes, imageData)
          
            if ~isvalid(targetAxes)
                return;
            end
            
            if isempty(imageData)
                return;
            end
            
            if size(imageData, 3) == 3
                imageData = rgb2gray(imageData);
                
                % Opsi 2 (jika ingin 3 plot): Anda perlu memodifikasi fungsi ini
                % untuk menerima R, G, B dan memplotnya dengan warna berbeda.
                % Untuk saat ini, kita buat sederhana dengan grayscale.
            end
            
            % Panggil fungsi kustom Anda untuk menghitung data histogram
            counts = custom_image_histogram(imageData);
            
            % Tampilkan data histogram menggunakan bar plot
            bar(targetAxes, 0:255, counts, 'FaceColor', 'k');
            
            % Atur properti axes
            grid(targetAxes, 'on');
            xlabel(targetAxes, 'Intensitas');
            ylabel(targetAxes, 'Jumlah Piksel');
            xlim(targetAxes, [0 255]);
         end

         function plotCustomHistogramRGBSeparate(app, targetAxes, imageData)


                % Fungsi ini untuk menampilkan histogram RGB dalam 3 subplot terpisah
                % Namun, karena UIAxes hanya 1 komponen, kita perlu create figure baru
                
                if isempty(imageData)
                    return;
                end
                
                if size(imageData, 3) == 3
                    % Buat figure baru untuk 3 subplot
                    fig = figure('Name', 'RGB Histogram', 'NumberTitle', 'off');
                    
                    % Pisahkan channel
                    R = imageData(:,:,1);
                    G = imageData(:,:,2);
                    B = imageData(:,:,3);
                    
                    % Hitung histogram
                    countR = custom_image_histogram(R);
                    countG = custom_image_histogram(G);
                    countB = custom_image_histogram(B);
                    
                    bins = 0:255;
                    
                    % Subplot 1: Red
                    subplot(3,1,1);
                    bar(bins, countR, 'r', 'EdgeColor', 'none');
                    title('Red Channel');
                    xlabel('Intensitas');
                    ylabel('Jumlah Piksel');
                    xlim([0 255]);
                    grid on;
                    
                    % Subplot 2: Green
                    subplot(3,1,2);
                    bar(bins, countG, 'g', 'EdgeColor', 'none');
                    title('Green Channel');
                    xlabel('Intensitas');
                    ylabel('Jumlah Piksel');
                    xlim([0 255]);
                    grid on;
                    
                    % Subplot 3: Blue
                    subplot(3,1,3);
                    bar(bins, countB, 'b', 'EdgeColor', 'none');
                    title('Blue Channel');
                    xlabel('Intensitas');
                    ylabel('Jumlah Piksel');
                    xlim([0 255]);
                    grid on;
                    
                else
                    % Grayscale - plot di targetAxes
                    cla(targetAxes, 'reset');
                    counts = custom_image_histogram(imageData);
                    bar(targetAxes, 0:255, counts, 'k', 'EdgeColor', 'none');
                    xlabel(targetAxes, 'Intensitas');
                    ylabel(targetAxes, 'Jumlah Piksel');
                    xlim(targetAxes, [0 255]);
                    grid(targetAxes, 'on');
                end
            end
    
    
         function main_1_histogram()
                img = browse_image();
            
                if isempty(img)
                    return;
                end
                % tampilkan citra
                figure;
                imshow(img);
                title('Citra Asli');
                % cek apakah gambar grayscale atau berwarna
            
                if size(img, 3) == 1
                    disp('Citra adalah gambar grayscale.');
                
                    % hitung histogram grayscale (hanya sekali)
                    counts = custom_image_histogram(img);
                    bins = 0:255;
            
                    %tampilkan histogram citra grayscale
                    figure;
                    bar(bins, counts, 'k');
                    title('Histogram Citra Grayscale (fungsi histogram dibuat sendiri)');
                    xlabel('Intensitas (0-255)');
                    ylabel('Jumlah Piksel');
                else
                    disp('Citra adalah gambar berwarna.');
                    %pisah matriks gambar RGB yang terdiri atas 3 channel menjadi matriks R, G, dan B
                    R = img(:,:,1);
                    G = img(:,:,2);
                    B = img(:,:,3);
            
                    % Hitung histogram tiap channel R,G,B (3 kali)
                    binsR = 0:255;
                    binsG = 0:255;
                    binsB = 0:255;
                    countR = custom_image_histogram(R);
                    countG = custom_image_histogram(G);
                    countB = custom_image_histogram(B);
                
                    % Tampilkan histogram RGB
                    figure;
                    bar(binsR, countR, 'r');
                    title('Histogram Citra R/Merah (fungsi histogram dibuat sendiri)');
                    xlabel('Intensitas (0-255)');
                    ylabel('Jumlah Piksel');
                    figure;
                    bar(binsG, countG, 'g');
                    title('Histogram Citra G/Hijau (fungsi histogram dibuat sendiri)');
                    xlabel('Intensitas (0-255)');
                    ylabel('Jumlah Piksel');
                    figure;
                    bar(binsB, countB, 'b');
                    title('Histogram Citra B/Biru (fungsi histogram dibuat sendiri)');
                    xlabel('Intensitas (0-255)');
                    ylabel('Jumlah Piksel');
                end
         end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(fullfile(pwd, 'soal2'));
            addpath(fullfile(pwd, 'soal4'));
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
                         
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg','Image Files (*.jpg,*.png,*.jpeg)'},'Pilih Gambar Input Utama');
            [~,~,x] = fileparts(file);
            app.ext = x;
            if isequal(file, 0)
                disp('User batal memilih file input utama');
                return;
            end
            
            app.inputPath = fullfile(path, file);
            app.inputImageData = imread(app.inputPath);
            
            app.inputimgfield.Value = file;
            app.inputImage.ImageSource = app.inputPath;
            app.inputImage.Visible = 'on';
            app.inputImageLabel.Visible = 'on';
            app.inputImageLabel.Text = 'Input Image';
            
            app.plotFourHistograms(app.inputImageData, 'inputHist');
        
        % handle input reference image di soall no 4
            if app.soalMenu.SelectedObject == app.soal4Button
                [file2, path2] = uigetfile({'*.jpg;*.png;*.jpeg','Image Files (*.jpg,*.png,*.jpeg)'},'Pilih Gambar Referensi');
                if isequal(file2, 0)
                    disp('User batal memilih file referensi');
                    app.refImg = [];
                    app.outputImage_2.ImageSource = '';
                    return;
                end
                
                fullPathRef = fullfile(path2, file2);
                app.refImg = imread(fullPathRef);
                
                app.outputImage_2.ImageSource = fullPathRef;
                app.outputImage_2.Visible = 'on';
                app.outputImageLabel_2.Visible = 'on';
                app.outputImageLabel_2.Text = 'Reference Image';
                
                app.plotFourHistograms(app.refImg, 'outputHist_2');
            else
                app.refImg = [];
                app.outputImage_2.ImageSource = '';
                app.outputImage_2.Visible = 'off';
                app.outputImageLabel_2.Visible = 'off';
            end
        end

        % Button pushed function: prosesButton
        function prosesButtonPushed(app, event)
            app.outputImage.ImageSource = '';
            app.outputImage_2.ImageSource = '';
            cla(app.outputHistB, 'reset'); % Cara benar untuk membersihkan UIAxes
            cla(app.outputHistG, 'reset'); % Cara benar untuk membersihkan UIAxes
            cla(app.outputHistR, 'reset'); % Cara benar untuk membersihkan UIAxes
            cla(app.outputHistGabungan, 'reset'); % Cara benar untuk membersihkan UIAxes
            cla(app.outputHist_2B, 'reset'); % Cara benar untuk membersihkan UIAxes
            cla(app.outputHist_2G, 'reset'); % Cara benar untuk membersihkan UIAxes
            cla(app.outputHist_2R, 'reset'); % Cara benar untuk membersihkan UIAxes
            cla(app.outputHist_2Gabungan, 'reset'); % Cara benar untuk membersihkan UIAxes

            % cla(app.outputHist, 'reset'); % Cara benar untuk membersihkan UIAxes
            inputFile = app.inputPath;
            inputImg = app.inputImageData;


            if ~isempty(inputImg)
                choice  = app.soalMenu.SelectedObject;

                if ~isempty(choice)

                    switch choice
                        case app.soal2Button
                            choicesubsoal = app.subSoal2Menu.SelectedObject;

                            if ~isempty (choicesubsoal)
                                switch choicesubsoal
                                    case app.soal2aButton
                                        brightener(app, inputImg , app.aEditField.Value, app.bEditField.Value );
                                    case app.soal2bButton
                                        negative(app, inputImg );
                                     case app.soal2cButton
                                        logTransform (app,inputImg , app.cEditField.Value)
                                    case app.soal2dButton
                                        powerTransform(app,inputImg , app.cEditField.Value, app.gammaEditField.Value)
                                    case app.soal2eButton
                                        contrastStrecth(app, inputImg )
                                end
                            end

                        case app.soal1Button
                  
                         
                        case app.soal3Button
                            custom_histogram_equalization(app,inputImg)
                        case app.soal4Button
                            hist_match(app, inputImg, app.refImg)


                    end
                end
            end    
        end

        % Selection changed function: soalMenu
        function soalChoice(app, event)
            choice = app.soalMenu.SelectedObject;

            app.subSoal2Menu.Visible = 'off';
            app.aEditField.Visible = 'off';
            app.bEditField.Visible = 'off';
            app.cEditField.Visible = 'off';
            app.gammaEditField.Visible = 'off';
        
            % --- LANGKAH 2: ATUR STATE BARU SESUAI PILIHAN ---
            switch choice
                case app.soal1Button
                    
                case app.soal2Button
                    app.subSoal2Menu.Visible = 'on';
                   
                    app.soal2aButton.Value = true;
                    
                    app.aEditField.Visible = 'on';
                    app.bEditField.Visible = 'on';
                    
        
                case app.soal3Button
                 
                    
                 case app.soal4Button  

            end
       

        end

        % Selection changed function: subSoal2Menu
        function soal2Choice(app, event)
            choice = app.subSoal2Menu.SelectedObject;
        
            % LANGKAH 1: RESET STATE
            app.aEditField.Visible = 'off';
            app.bEditField.Visible = 'off';
            app.cEditField.Visible = 'off';
            app.gammaEditField.Visible = 'off';
        
            % LANGKAH 2: ATUR STATE BARU DENGAN SWITCH
            switch choice
                case app.soal2aButton
                    app.aEditField.Visible = 'on';
                    app.bEditField.Visible = 'on';

                case app.soal2bButton

                case app.soal2cButton
                    app.cEditField.Visible = 'on';

                case app.soal2dButton
                    app.cEditField.Visible = 'on';
                    app.gammaEditField.Visible = 'on';
                
                case app.soal2eButton
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.inputHistR = uiaxes(app.UIFigure);
            title(app.inputHistR, 'Input Histogram Red')
            xlabel(app.inputHistR, 'X')
            ylabel(app.inputHistR, 'Y')
            zlabel(app.inputHistR, 'Z')
            app.inputHistR.Visible = 'off';
            app.inputHistG = uiaxes(app.UIFigure);
            title(app.inputHistG, {'Input Histogram Green'; ''})
            xlabel(app.inputHistG, 'X')
            ylabel(app.inputHistG, 'Y')
            zlabel(app.inputHistG, 'Z')
            app.inputHistG.Visible = 'off';
            app.inputHistB = uiaxes(app.UIFigure);
            title(app.inputHistB, 'Input Histogram Blue')
            xlabel(app.inputHistB, 'X')
            ylabel(app.inputHistB, 'Y')
            zlabel(app.inputHistB, 'Z')
            app.inputHistB.Visible = 'off';
            app.outputHistR = uiaxes(app.UIFigure);
            title(app.outputHistR, 'Output Histogram Red')
            xlabel(app.outputHistR, 'X')
            ylabel(app.outputHistR, 'Y')
            zlabel(app.outputHistR, 'Z')
            app.outputHistR.Visible = 'off';
            app.outputHistG = uiaxes(app.UIFigure);
            title(app.outputHistG, 'Output Histogram Green')
            xlabel(app.outputHistG, 'X')
            ylabel(app.outputHistG, 'Y')
            zlabel(app.outputHistG, 'Z')
            app.outputHistG.Visible = 'off';
            app.outputHistB = uiaxes(app.UIFigure);
            title(app.outputHistB, 'Output Histogram Blue')
            xlabel(app.outputHistB, 'X')
            ylabel(app.outputHistB, 'Y')
            zlabel(app.outputHistB, 'Z')
            app.outputHistB.Visible = 'off';
            app.outputHist_2R = uiaxes(app.UIFigure);
            title(app.outputHist_2R, 'Output Histogram Red')
            xlabel(app.outputHist_2R, 'X')
            ylabel(app.outputHist_2R, 'Y')
            zlabel(app.outputHist_2R, 'Z')
            app.outputHist_2R.Visible = 'off';
            app.outputHist_2G = uiaxes(app.UIFigure);
            title(app.outputHist_2G, 'Output Histogram Green')
            xlabel(app.outputHist_2G, 'X')
            ylabel(app.outputHist_2G, 'Y')
            zlabel(app.outputHist_2G, 'Z')
            app.outputHist_2G.Visible = 'off';
            app.outputHist_2B = uiaxes(app.UIFigure);
            title(app.outputHist_2B, 'Output Histogram Blue')
            xlabel(app.outputHist_2B, 'X')
            ylabel(app.outputHist_2B, 'Y')
            zlabel(app.outputHist_2B, 'Z')
            app.outputHist_2B.Visible = 'off';
            app.inputHistGabungan = uiaxes(app.UIFigure);
            title(app.inputHistGabungan, 'Input Histogram Gabungan')
            xlabel(app.inputHistGabungan, 'X')
            ylabel(app.inputHistGabungan, 'Y')
            zlabel(app.inputHistGabungan, 'Z')
            app.inputHistGabungan.Visible = 'off';
            app.outputHistGabungan = uiaxes(app.UIFigure);
            title(app.outputHistGabungan, 'Output Histogram Gabungan')
            xlabel(app.outputHistGabungan, 'X')
            ylabel(app.outputHistGabungan, 'Y')
            zlabel(app.outputHistGabungan, 'Z')
            app.outputHistGabungan.Visible = 'off';
            app.outputHist_2Gabungan = uiaxes(app.UIFigure);
            title(app.outputHist_2Gabungan, 'Output Histogram Gabungan')
            xlabel(app.outputHist_2Gabungan, 'X')
            ylabel(app.outputHist_2Gabungan, 'Y')
            zlabel(app.outputHist_2Gabungan, 'Z')
            app.outputHist_2Gabungan.Visible = 'off';
            app.inputimgfield = uieditfield(app.UIFigure, 'text');
            app.inputimgfield.Value = 'Gambar Input';
            app.browseButton = uibutton(app.UIFigure, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.prosesButton = uibutton(app.UIFigure, 'push');
            app.prosesButton.ButtonPushedFcn = createCallbackFcn(app, @prosesButtonPushed, true);
            app.prosesButton.Text = 'proses';
            app.inputImage = uiimage(app.UIFigure);
            app.inputImage.Visible = 'off';
            app.appTitle = uilabel(app.UIFigure);
            app.appTitle.Text = 'IMAGE EDITOR';
            app.inputImageLabel = uilabel(app.UIFigure);
            app.inputImageLabel.Visible = 'off';
            app.inputImageLabel.Text = 'Input Image';
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.Visible = 'off';
            app.aLabel = uilabel(app.UIFigure);
            app.aLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Visible = 'off';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Visible = 'off';
            app.gammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.gammaEditField.Visible = 'off';
            app.subSoal2Menu = uibuttongroup(app.UIFigure);
            app.subSoal2Menu.SelectionChangedFcn = createCallbackFcn(app, @soal2Choice, true);
            app.subSoal2Menu.Visible = 'off';
            app.soal2aButton = uiradiobutton(app.subSoal2Menu);
            app.soal2aButton.Text = 'Soal 2A';
            app.soal2aButton.Value = true;
            app.soal2bButton = uiradiobutton(app.subSoal2Menu);
            app.soal2bButton.Text = 'Soal 2B';
            app.soal2cButton = uiradiobutton(app.subSoal2Menu);
            app.soal2cButton.Text = 'Soal 2C';
            app.soal2dButton = uiradiobutton(app.subSoal2Menu);
            app.soal2dButton.Text = 'Soal 2D';
            app.soal2eButton = uiradiobutton(app.subSoal2Menu);
            app.soal2eButton.Text = 'Soal 2E';
            app.soalMenu = uibuttongroup(app.UIFigure);
            app.soalMenu.SelectionChangedFcn = createCallbackFcn(app, @soalChoice, true);
            app.soal1Button = uiradiobutton(app.soalMenu);
            app.soal1Button.Text = 'Soal 1';
            app.soal1Button.Value = true;
            app.soal2Button = uiradiobutton(app.soalMenu);
            app.soal2Button.Text = 'Soal 2';
            app.soal3Button = uiradiobutton(app.soalMenu);
            app.soal3Button.Text = 'Soal 3';
            app.soal4Button = uiradiobutton(app.soalMenu);
            app.soal4Button.Text = 'Soal 4';
            app.hasilLabel = uilabel(app.UIFigure);
            app.hasilLabel.Text = 'HASIL';
            app.cLabel = uilabel(app.UIFigure);
            app.cLabel.Text = 'c';
            app.gammaLabel = uilabel(app.UIFigure);
            app.gammaLabel.Text = 'gamma';
            app.outputImage = uiimage(app.UIFigure);
            app.outputImage.Visible = 'off';
            app.outputImageLabel = uilabel(app.UIFigure);
            app.outputImageLabel.Visible = 'off';
            app.outputImageLabel.Text = 'Output Image';
            app.outputImage_2 = uiimage(app.UIFigure);
            app.outputImage_2.Visible = 'off';
            app.outputImageLabel_2 = uilabel(app.UIFigure);
            app.outputImageLabel_2.Visible = 'off';
            app.outputImageLabel_2.Text = 'Output Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgediter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end